classdef beamProfileLoader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Beamprofileloaderforplan2DLabel  matlab.ui.control.Label
        NotreadyLampLabel       matlab.ui.control.Label
        NotreadyLamp            matlab.ui.control.Lamp
        ExitButton              matlab.ui.control.Button
        ConfirmButton           matlab.ui.control.Button
        Image                   matlab.ui.control.Image
        UIAxes                  matlab.ui.control.UIAxes
        ChoosefileButton        matlab.ui.control.Button
        dxdycmEditFieldLabel    matlab.ui.control.Label
        dxdycmEditField         matlab.ui.control.NumericEditField
        CreateGaussianButton    matlab.ui.control.Button
        sigmaXcmEditFieldLabel  matlab.ui.control.Label
        sigmaXcmEditField       matlab.ui.control.NumericEditField
        sigmaYcmEditFieldLabel  matlab.ui.control.Label
        sigmaYcmEditField       matlab.ui.control.NumericEditField
        CreatepinHoleButton     matlab.ui.control.Button
        radiuscmEditFieldLabel  matlab.ui.control.Label
        radiuscmEditField       matlab.ui.control.NumericEditField
        errMsgLabel             matlab.ui.control.Label
        XSizecmEditFieldLabel   matlab.ui.control.Label
        XSizecmEditField        matlab.ui.control.NumericEditField
        YSizecmEditFieldLabel   matlab.ui.control.Label
        YSizecmEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        beamProfile     % Description
        parentApp       % Description
        isReady % Description
    end
    
    methods (Access = private)
        
        function showErrorMsg(app, errMsg)
            app.errMsgLabel.Text = errMsg;
            app.errMsgLabel.Visible = true;
        end
        
        function clearErrorMsg(app)
            app.errMsgLabel.Text = '';
            app.errMsgLabel.Visible = false;
        end
        
        function verifyReady(app)          
            if ~isempty(app.beamProfile) && app.beamProfile.passMaxMinTests
                app.isReady = true;
                app.NotreadyLampLabel.Text = 'Ready';
                app.NotreadyLamp.Color = [0 1 0];
                app.clearErrorMsg
            else
                app.isReady = false;
                app.NotreadyLampLabel.Text = 'Not ready';
                app.NotreadyLamp.Color = [1 0 0];
                app.showErrorMsg('Beam profile not ready');
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.parentApp = parentApp;
            app.parentApp.logLine('Opening beam profile loader.')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.parentApp.logLine('Closing beam profile loader.')
            delete(app)
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            app.UIFigureCloseRequest(event);
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.verifyReady
            if app.isReady
                app.UIFigureCloseRequest(event);
            end
        end

        % Button pushed function: ChoosefileButton
        function ChoosefileButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Beamprofileloaderforplan2DLabel = uilabel(app.UIFigure);
            app.Beamprofileloaderforplan2DLabel.Text = 'Beam profile loader for plan2D';
            app.NotreadyLampLabel = uilabel(app.UIFigure);
            app.NotreadyLampLabel.Text = 'Not ready';
            app.NotreadyLamp = uilamp(app.UIFigure);
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.Image = uiimage(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ChoosefileButton = uibutton(app.UIFigure, 'push');
            app.ChoosefileButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefileButtonPushed, true);
            app.ChoosefileButton.Text = 'Choose file';
            app.dxdycmEditFieldLabel = uilabel(app.UIFigure);
            app.dxdycmEditFieldLabel.Text = 'dx / dy [cm]';
            app.dxdycmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreateGaussianButton = uibutton(app.UIFigure, 'push');
            app.CreateGaussianButton.Text = 'Create Gaussian';
            app.sigmaXcmEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaXcmEditFieldLabel.Text = 'sigmaX [cm]';
            app.sigmaXcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.sigmaYcmEditFieldLabel = uilabel(app.UIFigure);
            app.sigmaYcmEditFieldLabel.Text = 'sigmaY [cm]';
            app.sigmaYcmEditField = uieditfield(app.UIFigure, 'numeric');
            app.CreatepinHoleButton = uibutton(app.UIFigure, 'push');
            app.CreatepinHoleButton.Text = 'Create pinHole';
            app.radiuscmEditFieldLabel = uilabel(app.UIFigure);
            app.radiuscmEditFieldLabel.Text = 'radius [cm]';
            app.radiuscmEditField = uieditfield(app.UIFigure, 'numeric');
            app.errMsgLabel = uilabel(app.UIFigure);
            app.errMsgLabel.Text = '';
            app.XSizecmEditFieldLabel = uilabel(app.UIFigure);
            app.XSizecmEditFieldLabel.Text = 'XSize [cm]';
            app.XSizecmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YSizecmEditFieldLabel = uilabel(app.UIFigure);
            app.YSizecmEditFieldLabel.Text = 'YSize [cm]';
            app.YSizecmEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beamProfileLoader(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end