classdef plan2DGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        Image2                          matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        CDanielSnchezParcerisa2020Label  matlab.ui.control.Label
        dsparcerisaucmesLabel           matlab.ui.control.Label
        DoserateButtonGroup             matlab.ui.container.ButtonGroup
        FLASHButton                     matlab.ui.control.RadioButton
        StandardButton                  matlab.ui.control.RadioButton
        CenterPanel                     matlab.ui.container.Panel
        plan2DLabel                     matlab.ui.control.Label
        PlanFileEditFieldLabel          matlab.ui.control.Label
        PlanFileEditField               matlab.ui.control.EditField
        OpenButton                      matlab.ui.control.Button
        plannamevalueLabel              matlab.ui.control.Label
        DistancetotargetGaugeLabel      matlab.ui.control.Label
        DistancetotargetGauge           matlab.ui.control.LinearGauge
        EnergyGaugeLabel                matlab.ui.control.Label
        EnergyGauge                     matlab.ui.control.Gauge
        Image                           matlab.ui.control.Image
        pepperPotLabel                  matlab.ui.control.Label
        IntensitynAEditFieldLabel       matlab.ui.control.Label
        IntensitynAEditField            matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        distanceValueLabel              matlab.ui.control.Label
        TargetthicknessmEditFieldLabel  matlab.ui.control.Label
        TargetthicknessmEditField       matlab.ui.control.NumericEditField
        Targetdensitygcm3EditFieldLabel  matlab.ui.control.Label
        Targetdensitygcm3EditField      matlab.ui.control.NumericEditField
        TargetSPREditFieldLabel         matlab.ui.control.Label
        TargetSPREditField              matlab.ui.control.NumericEditField
        SimulateButton                  matlab.ui.control.Button
        DeliverButton                   matlab.ui.control.Button
        msGyEditFieldLabel              matlab.ui.control.Label
        msGyEditField                   matlab.ui.control.NumericEditField
        NotreadyLampLabel               matlab.ui.control.Label
        NotreadyLamp                    matlab.ui.control.Lamp
        ConfigureButton                 matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        UIAxes_3                        matlab.ui.control.UIAxes
        MessagelogTextAreaLabel         matlab.ui.control.Label
        MessagelogTextArea              matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        planPath    % path to plan File
        planLoadad  % boolean if plan is loaded
        N0          % beam profile at Z=0
        beamProfile % Description
    end
    
    methods (Access = public)
        
        function logLine(app, newLine)
            newLine = [datestr(now,'[HH:MM:SS]') ' ' newLine]; 
            app.MessagelogTextArea.Value = { newLine, app.MessagelogTextArea.Value{:} };
        end
        
        function setBeamProfile(app, beamProfile)
            app.N0 = beamProfile;
            imagesc(app.N0.getAxisValues('X'), ...
                app.N0.getAxisValues('Y'), ...
                app.N0.data, 'Parent', app.UIAxes);
            halfX = round(app.N0.maxX + app.N0.dx/2,1);
            halfY = round(app.N0.maxY + app.N0.dy/2,1);
            app.UIAxes.XTick = [-halfX halfX];
            app.UIAxes.XTickLabel = [-halfX halfX];
            app.UIAxes.YTick = [-halfY halfY];
            app.UIAxes.YTickLabel = [-halfY halfY];
            app.logLine('Beam profile loaded');            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logLine('plan2D started.');
            app.logLine('No plan loaded.');
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            simPlanIrradiation(app);
        end

        % Button pushed function: DeliverButton
        function DeliverButtonPushed(app, event)
            irradiatePlan(app);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*');
            app.planPath = fullfile(filePath, fileName);
            app.PlanFileEditField.Value = app.planPath;
            readPlan(app.planPath, app);
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            beamProfileLoader(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {256, '1x', 258};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes_2 = uiaxes(app.LeftPanel);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, 'Expected dose distribution')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'LogoGFN.png';
            app.Image3 = uiimage(app.LeftPanel);
            app.Image3.ImageSource = 'LogoUCM.jpg';
            app.CDanielSnchezParcerisa2020Label = uilabel(app.LeftPanel);
            app.CDanielSnchezParcerisa2020Label.Text = '(C) Daniel Sánchez Parcerisa, 2020';
            app.dsparcerisaucmesLabel = uilabel(app.LeftPanel);
            app.dsparcerisaucmesLabel.Text = 'dsparcerisa@ucm.es';
            app.DoserateButtonGroup = uibuttongroup(app.LeftPanel);
            app.DoserateButtonGroup.Title = 'Dose rate';
            app.FLASHButton = uiradiobutton(app.DoserateButtonGroup);
            app.FLASHButton.Text = 'FLASH';
            app.FLASHButton.Value = true;
            app.StandardButton = uiradiobutton(app.DoserateButtonGroup);
            app.StandardButton.Text = 'Standard';
            app.CenterPanel = uipanel(app.GridLayout);
            app.plan2DLabel = uilabel(app.CenterPanel);
            app.plan2DLabel.Text = 'plan2D';
            app.PlanFileEditFieldLabel = uilabel(app.CenterPanel);
            app.PlanFileEditFieldLabel.Text = 'Plan File';
            app.PlanFileEditField = uieditfield(app.CenterPanel, 'text');
            app.PlanFileEditField.Editable = 'off';
            app.OpenButton = uibutton(app.CenterPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.plannamevalueLabel = uilabel(app.CenterPanel);
            app.plannamevalueLabel.Text = '[plan name value]';
            app.DistancetotargetGaugeLabel = uilabel(app.CenterPanel);
            app.DistancetotargetGaugeLabel.Text = 'Distance to target';
            app.DistancetotargetGauge = uigauge(app.CenterPanel, 'linear');
            app.DistancetotargetGauge.Limits = [0 20];
            app.EnergyGaugeLabel = uilabel(app.CenterPanel);
            app.EnergyGaugeLabel.Text = 'Energy';
            app.EnergyGauge = uigauge(app.CenterPanel, 'circular');
            app.EnergyGauge.Limits = [0 10];
            app.Image = uiimage(app.CenterPanel);
            app.pepperPotLabel = uilabel(app.CenterPanel);
            app.pepperPotLabel.Text = 'pepperPot';
            app.IntensitynAEditFieldLabel = uilabel(app.CenterPanel);
            app.IntensitynAEditFieldLabel.Text = 'Intensity [nA]';
            app.IntensitynAEditField = uieditfield(app.CenterPanel, 'numeric');
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Beam profile')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.distanceValueLabel = uilabel(app.CenterPanel);
            app.distanceValueLabel.Text = '[distanceValue]';
            app.TargetthicknessmEditFieldLabel = uilabel(app.CenterPanel);
            app.TargetthicknessmEditFieldLabel.Text = 'Target thickness [µm]';
            app.TargetthicknessmEditField = uieditfield(app.CenterPanel, 'numeric');
            app.Targetdensitygcm3EditFieldLabel = uilabel(app.CenterPanel);
            app.Targetdensitygcm3EditFieldLabel.Text = 'Target density [g/cm3]';
            app.Targetdensitygcm3EditField = uieditfield(app.CenterPanel, 'numeric');
            app.TargetSPREditFieldLabel = uilabel(app.CenterPanel);
            app.TargetSPREditFieldLabel.Text = 'Target SPR';
            app.TargetSPREditField = uieditfield(app.CenterPanel, 'numeric');
            app.SimulateButton = uibutton(app.CenterPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.DeliverButton = uibutton(app.CenterPanel, 'push');
            app.DeliverButton.ButtonPushedFcn = createCallbackFcn(app, @DeliverButtonPushed, true);
            app.DeliverButton.Text = 'Deliver';
            app.msGyEditFieldLabel = uilabel(app.CenterPanel);
            app.msGyEditFieldLabel.Text = 'ms/Gy';
            app.msGyEditField = uieditfield(app.CenterPanel, 'numeric');
            app.msGyEditField.Editable = 'off';
            app.NotreadyLampLabel = uilabel(app.CenterPanel);
            app.NotreadyLampLabel.Text = 'Not ready';
            app.NotreadyLamp = uilamp(app.CenterPanel);
            app.ConfigureButton = uibutton(app.CenterPanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes_3 = uiaxes(app.RightPanel);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, 'Delivered dose distribution')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.MessagelogTextAreaLabel = uilabel(app.RightPanel);
            app.MessagelogTextAreaLabel.Text = 'Message log';
            app.MessagelogTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plan2DGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end